classdef Scint_City < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScintCityUIFigure               matlab.ui.Figure
        RunScintCityButton              matlab.ui.control.Button
        dirnameEditFieldLabel           matlab.ui.control.Label
        dirnameEditField                matlab.ui.control.EditField
        SavefigsSwitchLabel             matlab.ui.control.Label
        SavefigsSwitch                  matlab.ui.control.Switch
        StructureparametersPanel        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        n_substrateEditField            matlab.ui.control.NumericEditField
        n_substrateEditFieldLabel       matlab.ui.control.Label
        n_scintEditField                matlab.ui.control.NumericEditField
        n_scintEditFieldLabel           matlab.ui.control.Label
        n_otherEditField                matlab.ui.control.NumericEditField
        n_otherEditFieldLabel           matlab.ui.control.Label
        pairsEditFieldLabel             matlab.ui.control.Label
        pairsEditField                  matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        OptimizationTab                 matlab.ui.container.Tab
        OptimizeSwitchLabel             matlab.ui.control.Label
        OptimizeSwitch                  matlab.ui.control.Switch
        NumberofOptimizationsEditField  matlab.ui.control.NumericEditField
        NumberofOptimizationsEditFieldLabel  matlab.ui.control.Label
        TotalsizeEditFieldLabel         matlab.ui.control.Label
        TotalsizeEditField              matlab.ui.control.NumericEditField
        ConstraintsDropDownLabel        matlab.ui.control.Label
        ConstraintsDropDown             matlab.ui.control.DropDown
        DirectionalitySwitchLabel       matlab.ui.control.Label
        DirectionalitySwitch            matlab.ui.control.Switch
        OptimizationMethodLabel         matlab.ui.control.Label
        LowerboundEditFieldLabel        matlab.ui.control.Label
        LowerboundEditField             matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel        matlab.ui.control.Label
        UpperboundEditField             matlab.ui.control.NumericEditField
        KnownStructureTab               matlab.ui.container.Tab
        dEditField                      matlab.ui.control.EditField
        dEditFieldLabel                 matlab.ui.control.Label
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.EditField
        scintindexLabel                 matlab.ui.control.Label
        scintindexEditField             matlab.ui.control.EditField
        ImageProcessingTab              matlab.ui.container.Tab
        DistancebetweenDeviceandCameraEditFieldLabel  matlab.ui.control.Label
        DistancebetweenDeviceandCameraEditField  matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        VarianceofOnionNoiseEditFieldLabel  matlab.ui.control.Label
        VarianceofOnionNoiseEditField   matlab.ui.control.NumericEditField
        GeneralParametersPanel          matlab.ui.container.Panel
        thetaEditFieldLabel             matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.EditField
        YmuEditField                    matlab.ui.control.NumericEditField
        YmuEditFieldLabel               matlab.ui.control.Label
        YsigmaEditField                 matlab.ui.control.NumericEditField
        YsigmaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.EditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        dzNzEditFieldLabel              matlab.ui.control.Label
        dzNzEditField                   matlab.ui.control.NumericEditField
        GzSwitchLabel                   matlab.ui.control.Label
        GzSwitch                        matlab.ui.control.Switch
        SimulationPanel                 matlab.ui.container.Panel
        RunSimulationSwitchLabel        matlab.ui.control.Label
        RunSimulationSwitch             matlab.ui.control.Switch
        LumericalpathEditFieldLabel     matlab.ui.control.Label
        LumericalpathEditField          matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunScintCityButton
        function RunScintCityButtonPushed(app, event)
            
            theta_vec = str2double(strsplit(app.thetaEditField.Value));
            if length(theta_vec) == 1
               theta = theta_vec(1); 
            else
               theta = linspace(theta_vec(1), theta_vec(2), theta_vec(3)); 
            end
            
            lambda_vec = str2double(strsplit(app.lambdaEditField.Value));
            if length(lambda_vec) == 1
               lambda = lambda_vec(1); 
            else
               lambda = linspace(lambda_vec(1), lambda_vec(2), lambda_vec(3)); 
            end
            
            pairs_vec = str2double(strsplit(app.pairsEditField.Value));
            if length(pairs_vec) == 1
               pairs = pairs_vec(1); 
            else
               pairs = linspace(pairs_vec(1), pairs_vec(2), pairs_vec(3)); 
            end
            
            if isempty(app.dEditField.Value)
                d = [];
            else
                d = str2double(strsplit(app.dEditField.Value));
            end
            
            if isempty(app.nEditField.Value)
                n = [];
            else
                n = str2double(strsplit(app.nEditField.Value));
            end
            
            if isempty(app.scintindexEditField.Value)
                i_scint = [];
            else
                i_scint = str2double(strsplit(app.scintindexEditField.Value));
            end
            
            if app.GzSwitch.Value == "dz"
                is_Gz = false;
                dz_Nz = app.dzNzEditField.Value;
            else
                is_Gz = true;
                dz_Nz = app.dzNzEditField.Value;
            end
            
            mu_lambda       = app.YmuEditField.Value;
            sigma_lambda    = app.YsigmaEditField.Value;
            n_si            = app.n_substrateEditField.Value;
            n_scint         = app.n_scintEditField.Value;
            n_ox            = app.n_otherEditField.Value;
            num_iterations  = app.NumberofOptimizationsEditField.Value;
            total_size      = app.TotalsizeEditField.Value;
            lower_bound     = app.LowerboundEditField.Value;
            upper_bound     = app.UpperboundEditField.Value;
            constraint      = str2double(app.ConstraintsDropDown.Value(1));
            optimize        = app.OptimizeSwitch.Value ~= "Known Structure";
            optimization_method = app.DirectionalitySwitch.Value;
            save_fig        = app.SavefigsSwitch.Value == "On";
            dir_name        = app.dirnameEditField.Value;
            
            ImageProcessing_Params.nbins = app.NumberofBinsEditField.Value;
            ImageProcessing_Params.h     = app.DistancebetweenDeviceandCameraEditField.Value;
            ImageProcessing_Params.sigma_onion_noise = app.VarianceofOnionNoiseEditField.Value;
            
            simulation_params.simulate = app.RunSimulationSwitch.Value == "On";
            simulation_params.lumerical_path = app.LumericalpathEditField.Value;
            
            progress_bar = uiprogressdlg(uifigure,'Title','Computing Scint City','Indeterminate','on');

            set(groot, 'defaultFigurePosition', [100 100 900 600]); % figure size
            set(groot, 'defaultTextInterpreter', 'latex'); % latex
            set(groot, 'defaultLegendInterpreter', 'latex'); % latex
            set(groot, 'defaultAxesTickLabelInterpreter','latex'); % latex
            set(0, 'DefaultLineLineWidth', 4);
            
            One_D_PhC_Optimization( dz_Nz,          ...
                                    theta,          ...
                                    lambda,         ...
                                    mu_lambda,      ...
                                    sigma_lambda,   ...
                                    pairs,          ...
                                    n_si,           ...
                                    n_scint,        ...
                                    n_ox,           ...
                                    d,              ...
                                    n,              ...
                                    i_scint,        ...
                                    is_Gz,          ...
                                    num_iterations, ...
                                    total_size,     ...
                                    lower_bound,    ...
                                    upper_bound,    ...
                                    constraint,     ...
                                    optimize,       ...
                                    optimization_method,       ...
                                    ImageProcessing_Params, ...
                                    save_fig,       ...
                                    dir_name,       ...
                                    simulation_params);
                                
            % close the dialog box
            close(progress_bar);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScintCityUIFigure = uifigure('Visible', 'off');
            app.ScintCityUIFigure.Name = 'Scint City';
            app.RunScintCityButton = uibutton(app.ScintCityUIFigure, 'push');
            app.RunScintCityButton.ButtonPushedFcn = createCallbackFcn(app, @RunScintCityButtonPushed, true);
            app.RunScintCityButton.Text = 'Run Scint-City';
            app.dirnameEditFieldLabel = uilabel(app.ScintCityUIFigure);
            app.dirnameEditFieldLabel.Text = 'dir name';
            app.dirnameEditField = uieditfield(app.ScintCityUIFigure, 'text');
            app.dirnameEditField.Value = 'Results';
            app.SavefigsSwitchLabel = uilabel(app.ScintCityUIFigure);
            app.SavefigsSwitchLabel.Text = 'Save figs';
            app.SavefigsSwitch = uiswitch(app.ScintCityUIFigure, 'slider');
            app.StructureparametersPanel = uipanel(app.ScintCityUIFigure);
            app.StructureparametersPanel.Title = 'Structure parameters';
            app.GridLayout = uigridlayout(app.StructureparametersPanel);
            app.n_substrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_substrateEditField.Value = 4.3;
            app.n_substrateEditFieldLabel = uilabel(app.GridLayout);
            app.n_substrateEditFieldLabel.Text = 'n_substrate';
            app.n_scintEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_scintEditField.Value = 1.6;
            app.n_scintEditFieldLabel = uilabel(app.GridLayout);
            app.n_scintEditFieldLabel.Text = 'n_scint';
            app.n_otherEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_otherEditField.Value = 2.1;
            app.n_otherEditFieldLabel = uilabel(app.GridLayout);
            app.n_otherEditFieldLabel.Text = 'n_other';
            app.pairsEditFieldLabel = uilabel(app.GridLayout);
            app.pairsEditFieldLabel.Text = 'pairs';
            app.pairsEditField = uieditfield(app.GridLayout, 'text');
            app.pairsEditField.Value = '10';
            app.TabGroup = uitabgroup(app.ScintCityUIFigure);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.OptimizeSwitchLabel = uilabel(app.OptimizationTab);
            app.OptimizeSwitchLabel.Text = 'Optimize';
            app.OptimizeSwitch = uiswitch(app.OptimizationTab, 'slider');
            app.OptimizeSwitch.Items = {'', 'Known Structure'};
            app.OptimizeSwitch.Value = '';
            app.NumberofOptimizationsEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.NumberofOptimizationsEditField.Value = 10;
            app.NumberofOptimizationsEditFieldLabel = uilabel(app.OptimizationTab);
            app.NumberofOptimizationsEditFieldLabel.Text = 'Number of Optimizations';
            app.TotalsizeEditFieldLabel = uilabel(app.OptimizationTab);
            app.TotalsizeEditFieldLabel.Text = 'Total size';
            app.TotalsizeEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.TotalsizeEditField.Value = 1000;
            app.ConstraintsDropDownLabel = uilabel(app.OptimizationTab);
            app.ConstraintsDropDownLabel.Text = 'Constraints';
            app.ConstraintsDropDown = uidropdown(app.OptimizationTab);
            app.ConstraintsDropDown.Items = {'1: Total scint and Total other equal Total size', '2: Each layer is between 0 and Total size', '3: Total size of the structure (Scint + Other) eqaul to Total size'};
            app.ConstraintsDropDown.Value = '1: Total scint and Total other equal Total size';
            app.DirectionalitySwitchLabel = uilabel(app.OptimizationTab);
            app.DirectionalitySwitchLabel.Text = 'Directionality';
            app.DirectionalitySwitch = uiswitch(app.OptimizationTab, 'slider');
            app.DirectionalitySwitch.Items = {'', 'Efficiency'};
            app.DirectionalitySwitch.Value = '';
            app.OptimizationMethodLabel = uilabel(app.OptimizationTab);
            app.OptimizationMethodLabel.Text = 'Optimization Method:';
            app.LowerboundEditFieldLabel = uilabel(app.OptimizationTab);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.LowerboundEditField.Value = 20;
            app.UpperboundEditFieldLabel = uilabel(app.OptimizationTab);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.UpperboundEditField.Value = 1000;
            app.KnownStructureTab = uitab(app.TabGroup);
            app.KnownStructureTab.Title = 'Known Structure';
            app.dEditField = uieditfield(app.KnownStructureTab, 'text');
            app.dEditFieldLabel = uilabel(app.KnownStructureTab);
            app.dEditFieldLabel.Text = 'd';
            app.nEditFieldLabel = uilabel(app.KnownStructureTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.KnownStructureTab, 'text');
            app.scintindexLabel = uilabel(app.KnownStructureTab);
            app.scintindexLabel.Text = 'scint index';
            app.scintindexEditField = uieditfield(app.KnownStructureTab, 'text');
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.DistancebetweenDeviceandCameraEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.DistancebetweenDeviceandCameraEditFieldLabel.Text = 'Distance between Device and Camera';
            app.DistancebetweenDeviceandCameraEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.NumberofBinsEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.NumberofBinsEditField.Value = 40;
            app.VarianceofOnionNoiseEditFieldLabel = uilabel(app.ImageProcessingTab);
            app.VarianceofOnionNoiseEditFieldLabel.Text = 'Variance of Onion Noise';
            app.VarianceofOnionNoiseEditField = uieditfield(app.ImageProcessingTab, 'numeric');
            app.VarianceofOnionNoiseEditField.Value = 1;
            app.GeneralParametersPanel = uipanel(app.ScintCityUIFigure);
            app.GeneralParametersPanel.Title = 'General Parameters';
            app.thetaEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.GeneralParametersPanel, 'text');
            app.thetaEditField.Value = '0';
            app.YmuEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.YmuEditField.Value = 430;
            app.YmuEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.YmuEditFieldLabel.Text = 'Y mu';
            app.YsigmaEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.YsigmaEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.YsigmaEditFieldLabel.Text = 'Y sigma';
            app.lambdaEditField = uieditfield(app.GeneralParametersPanel, 'text');
            app.lambdaEditField.Value = '410 450 301';
            app.lambdaEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.dzNzEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.dzNzEditFieldLabel.Text = 'dz/Nz';
            app.dzNzEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.dzNzEditField.Value = 10;
            app.GzSwitchLabel = uilabel(app.GeneralParametersPanel);
            app.GzSwitchLabel.Text = 'G(z)';
            app.GzSwitch = uiswitch(app.GeneralParametersPanel, 'slider');
            app.GzSwitch.Items = {'', 'dz'};
            app.GzSwitch.Value = '';
            app.SimulationPanel = uipanel(app.ScintCityUIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.RunSimulationSwitchLabel = uilabel(app.SimulationPanel);
            app.RunSimulationSwitchLabel.Text = 'Run Simulation';
            app.RunSimulationSwitch = uiswitch(app.SimulationPanel, 'slider');
            app.LumericalpathEditFieldLabel = uilabel(app.SimulationPanel);
            app.LumericalpathEditFieldLabel.Text = 'Lumerical path';
            app.LumericalpathEditField = uieditfield(app.SimulationPanel, 'text');
            app.LumericalpathEditField.Value = 'C:\Program Files\Lumerical\FDTD\bin\fdtd-solutions';
            % Show the figure after all components are created
            app.ScintCityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scint_City

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScintCityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScintCityUIFigure)
        end
    end
end